classdef Hydroponics_Final_real < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FLabel              matlab.ui.control.Label
        DateLabel           matlab.ui.control.Label
        DippHstripButton    matlab.ui.control.Button
        pHLabel             matlab.ui.control.Label
        PlantGrowthLabel_2  matlab.ui.control.Label
        PlantHeightinLabel  matlab.ui.control.Label
        LIGHTLEVLabel       matlab.ui.control.Label
        TEMPLabel           matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        Image               matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        UIAxes              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        a %arduino
        s1 %servo motor
        pH_amount = 0
        d = string(datetime('now'))
        temp_volts = 5;
        TempK = 0;
        reading = "null"
        plantheight;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = arduino();%change port
            app.s1 = servo(app.a, 'D6', 'MinPulseDuration', 533*10^-6, 'MaxPulseDuration', 2425*10^-6);
            counter = 0;   
            % get plant height from image
                im = imread("ThreePlantsHeight1.PNG");
                imshow(im);

                i_Red = im(:,:,1);
                i_Green = im(:,:,2);
                i_Blue = im(:,:,3);

                imshow(i_Blue)
                imshow(i_Red)
                imshow(i_Green)

                ThrBlue = imbinarize(i_Blue, "global");
                ThrGreen = imbinarize(i_Green, "global");
                ThrRed = imbinarize(i_Red, "global");
                imshow(ThrRed)
                imshow(ThrGreen)
                imshow(ThrBlue)
   
                ThrSum = (~ThrRed&~ThrGreen&~ThrBlue);
                bw = bwareaopen(ThrSum, 20000);
                imshowpair(bw, im, "montage");

                subplot(2,3,1), imshow(ThrRed)
                title('Red Panel')
                subplot(2,3,2), imshow(ThrGreen)
                title('Green Panel')
                subplot(2,3,3), imshow(ThrBlue)
                title('Blue Panel')
                subplot(2,3,4), imshow(bw)
                title('Sum')
                subplot(2,3,5), imshow(bw)
                title('Bounding Box')

                s = regionprops(bw, 'BoundingBox');

                
                im = imread("ThreePlantsHeight1.PNG");
                imshow(im);

                for k = 1 : length(s)

                hold on;
                rectangle('Position',s(k).BoundingBox)
                disp (s(k))
                hold off
                end

                bbTable = regionprops('table', bw, 'BoundingBox');
                bbArray = bbTable{:,:};
 
                x_coordinate = bbArray(:,1);
                y_coordinate = bbArray(:,2);
                width = bbArray(:,3);
                height = bbArray(:,4);

                disp(bbTable);
                

                TableOfHeight = regionprops('table', bw, 'BoundingBox');

                plantPixel = TableOfHeight{:,:};
                referenceInchHeight = 1;

                scaleFactor = referenceInchHeight/plantPixel(1,4);

                referenceHeight = scaleFactor*plantPixel(1,4);
                firstPlantHeight = scaleFactor*plantPixel(2,4);
                secondPlantHeight = scaleFactor*plantPixel(3,4);
                thirdPlantHeight = scaleFactor*plantPixel(4,4);
                
                app.plantheight = (firstPlantHeight + secondPlantHeight + thirdPlantHeight) / 3;
                %get plant hegiht from image end

            while true
                
                %date
                app.DateLabel.Text = app.d;
                %date end

                %get temperature (A0)
                %reading voltage
                app.temp_volts = readVoltage(app.a,'A0');
                %transforming voltage to tempK
                R2=10000;% the value of the precision resistor
                temp0 = 298.15;% reference temperature (25 C), in Kelvin
                res0 = 10000;% resistance at reference temperature, in Ohms
                B = 3950;% thermistor B parameter, in Kelvin
                current=app.temp_volts/R2;% calculate the current using Ohm's law
                Resistance=(5-app.temp_volts)/current;% find the resistance of the thermistor
                recip_temp = 1/temp0 + log(Resistance/res0)/B;% Calculate the reciprocal of the TempK
                app.TempK = 1./recip_temp;
                app.TEMPLabel.Text = num2str(app.temp_volts,3);
                switch app.DropDown.Value
                case 'Celsius' % When the string is "Celsius"
                TempC = app.TempK - 273.15; % Convert Kelvin to Celsius
                app.TEMPLabel.Text = num2str(TempC,3); % Convert the value to a string and assign to the label. Precision 3
                case 'Fahrenheit' % When the string is "Fahrenheit"
                TempF=((app.TempK-273.15)*1.8)+32; % Convert Kelvin to Fahrenheit
                app.TEMPLabel.Text = num2str(TempF,3); % Convert the value to a string and assign to the label. Precision 3
                end
                %get temp end
                
                % LIGHT LEVELS %
                volts_photo = readVoltage(app.a, 'A1');
            
                % I use a 0-10 scale for simplicity rather than 0-5
                volts = volts_photo * 2;
            
                % initialize to "null" to make clear if there is error
                
            
                if (volts >= 8)
                    app.reading = "Bright";
                elseif (volts >= 6)
                    app.reading = "Normal";
                else
                    app.reading = "Low";
                end
            
                app.LIGHTLEVLabel.Text = app.reading;

                
                pause(5)
                app.d = string(datetime('now'));
                if counter == 10
                %plot plant height graph and simulate growth
                header = ["date", "plant_height"];
                writematrix(header, 'Plant_height.xlsx');
                x1 = {app.d, app.plantheight};
                writecell(x1, 'Plant_height.xlsx', 'WriteMode', 'append')
                dataForPlotting = readtable('Plant_height.xlsx',"TextType", "string");
                x = categorical([dataForPlotting.date]);
                plot(app.UIAxes, x, dataForPlotting.plant_height);
                app.plantheight = app.plantheight + 3;
                app.PlantGrowthLabel_2.Text = num2str(app.plantheight,3);
                %plot plant height graph end
                counter = 0;
                end
                
                counter = counter + 1;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            switch app.DropDown.Value 
            case 'Celsius' % When the string is "Celsius"
            TempC = app.TempK - 273.15; % Convert Kelvin to Celsius
            app.TEMPLabel.Text = num2str(TempC,3); % Convert the value to a string and assign to the label. Precision 3
            app.FLabel.Text = "ºC";
            case 'Fahrenheit' % When the string is "Fahrenheit"
            TempF=((app.TempK-273.15)*1.8)+32; % Convert Kelvin to Fahrenheit
            app.TEMPLabel.Text = num2str(TempF,3); % Convert the value to a string and assign to the label. Precision 3
            app.FLabel.Text = "ºF";
            end
        end

        % Button pushed function: DippHstripButton
        function DippHstripButtonPushed(app, event)
            writePosition(app.s1, 0); % Send the smallest pulse to the servo to set it to the lowest position
            pause (1.5);
            writePosition(app.s1, 1); % Send the highest pulse to set it to the highest position
            pause(1.5)
                
                 % create video object (1 or 2)
            cam = webcam(1);
                
                % take a picture with the camera, 
                % place within control structure if timing is important
            img = snapshot(cam);
                
                % show the image
                % color correction might be necessary
            imshow(img);
            
            % pH ANALYSIS CODE %
            
                % initialize image
            pHimage = img;
                 
                % split image into RGB channels
            r_channel = pHimage(:,:,1);
            g_channel = pHimage(:,:,2);
            b_channel = pHimage(:,:,3);
                
                % define RGB ratios
            rg_ratio = double(r_channel) ./ double(g_channel);
            gb_ratio = double(g_channel) ./ double(b_channel);
            br_ratio = double(b_channel) ./ double(r_channel);
                
                % catch NaN ratios
            rg_ratio(isnan(rg_ratio)) = 0;
            gb_ratio(isnan(gb_ratio)) = 0;
            br_ratio(isnan(br_ratio)) = 0;
                
            % MASK AND BOUNDING BOX CODE
            
                % define region of interest based on RGB values of interest
                % in this case, replace anything with white/black-like RGB ratio with black,
                % keep anything lower than that ratio white and above the black ratio
                % modify this if the black/white mask doesn't look right
            roi = (rg_ratio <= 0.92 | gb_ratio <= 0.92 | br_ratio <= 0.92) & (rg_ratio >= 0.05 | gb_ratio >= 0.05 | br_ratio >= 0.05);
                
                % create black and white mask
                % 300 connects all pixel clumps bigger than 300
                % modify 300 if the mask is right but you get more boxes than you want
            bw = bwareaopen(roi, 350);
                
                % create bounding box around white objects in binary image
            bbTable = regionprops('table', bw, 'BoundingBox');
                % transfer tables values to array to make it more manageable
            bbArray = bbTable{:,:};
                
                % transfer each column of bbArray so similar values are grouped together
                % for example, in bb_x, the x values of all the rectangles are saved so
                % they can be looped and drawn
            bb_x = bbArray(:, 1);
            bb_y = bbArray(:, 2);
            bb_width = bbArray(:, 3);
            bb_height = bbArray(:, 4);
                
                % display image
                % imshowpair(bw, pHimage, 'montage');
            imshow(pHimage);
                
                % draw bounding boxes on image
            hold on
            for c = 1:size(bbArray, 1)
                rectangle('Position', [bb_x(c, 1), bb_y(c,1), bb_width(c,1), bb_height(c,1)],...
                'EdgeColor','r', 'LineWidth', 3)
            end
            hold off
                
            % FILTERING EXTRA BOUNDING BOXES
                
                % not ready
                % remove all elements that aren't the right RATIO or AREA
                %%for c = 1:size(bbArray, 1)
                    %if(bb_width(c, 1) / bb_height(c, 1) < 2 | bb_width(c, 1) / bb_height(c, 1) > 3)
                     %   bbArray(c,:) = [];
                    %end
                %end
                
            error = false;
                
            if size(bbArray, 1) ~= 16
                error = true;
            end
                
            % NEW CODE %
            
                % initialize closest color variables
            lowest_difference = 600;
            closest_pH = 15;
                
            try
                    % sort boxes in bbArray to match order on pH card
                pH_card_box_array = [sortrows(bbArray(1:8,:),2); sortrows(bbArray(9:15,:),2)];
                    
                    % display sorted bbArray for debugging
                    % disp(pH_card_box_array);
                    
                    % split pH card box values into related values
                pH_x = pH_card_box_array(:, 1);
                pH_y = pH_card_box_array(:, 2);
                pH_width = pH_card_box_array(:, 3);
                pH_height = pH_card_box_array(:, 4);
                    
                    % assign pH strip to last (right-most) in the list
                pH_strip_x = round(bb_x(16, 1) + (bb_width(16, 1) / 2)); 
                pH_strip_y = round(bb_y(16, 1) + (bb_height(16, 1) / 2));
                    
                    % assign RGB value to variables
                pH_strip_red = r_channel(pH_strip_y, pH_strip_x, :);
                pH_strip_green = g_channel(pH_strip_y, pH_strip_x, :);
                pH_strip_blue = b_channel(pH_strip_y, pH_strip_x, :);
                    
                    % initialize empty array for pH card RGB values as ordered on pH card
                pH_card_rgb_array = [7, 0, 0, 0; 6, 0, 0, 0; 5, 0, 0, 0; 4, 0, 0, 0; 3, 0, 0, 0; 2, 0, 0, 0; 1, 0, 0, 0; 0, 0, 0, 0; 14, 0, 0, 0; 13, 0, 0, 0; 12, 0, 0, 0; 11, 0, 0, 0; 10, 0, 0, 0; 9, 0, 0, 0; 8, 0, 0, 0];
                    
                    % assign RGB values to each pH
                for c = 1:size(pH_card_box_array, 1)
                    pH_card_x = round(pH_x(c, 1) + (pH_width(c, 1) / 2));
                    pH_card_y = round(pH_y(c, 1) + (pH_height(c, 1) / 2));
                    
                    pH_card_red = r_channel(pH_card_y, pH_card_x, :);
                    pH_card_green = g_channel(pH_card_y, pH_card_x, :);
                    pH_card_blue = b_channel(pH_card_y, pH_card_x, :);
                    
                    pH_card_rgb_array(c, 2) = pH_card_red;
                    pH_card_rgb_array(c, 3) = pH_card_green;
                    pH_card_rgb_array(c, 4) = pH_card_blue;
                    
                end
                
            catch
                    % assign pH strip to last (right-most) in the list
                pH_strip_x = round(bb_x(size(bb_x, 1), 1) + (bb_width(size(bb_width, 1), 1) / 2)); 
                pH_strip_y = round(bb_y(size(bb_y, 1), 1) + (bb_height(size(bb_height, 1), 1) / 2));
            
                    % get RGB values of pH strip
                pH_strip_red = r_channel(pH_strip_y, pH_strip_x, :);
                pH_strip_green = g_channel(pH_strip_y, pH_strip_x, :);
                pH_strip_blue = b_channel(pH_strip_y, pH_strip_x, :);
            
                    % initialize array for pH card RGB values as ordered on pH card
                    % filled with pure HUE values as found on Fisher Scientific website
                pH_card_rgb_array = [7, 105, 152, 89; 6, 188, 189, 78; 5, 208, 190, 69; 4, 224, 179, 89; 3, 223, 134, 69; 2, 211, 106, 62; 1, 213, 75, 90; 0, 193, 46, 97; 14, 3, 23, 72; 13, 16, 50, 87; 12, 44, 69, 90; 11, 35, 87, 84; 10, 42, 94, 85; 9, 66, 106, 81; 8, 91, 132, 79];
            
            end
            
                % NEW CODE END %
            
                % for each RGB value, calculate the difference and check if lowest
                % difference
            for c = 1:size(pH_card_rgb_array, 1)
                difference_red = double(pH_strip_red) - double(pH_card_rgb_array(c, 2));
                difference_green = double(pH_strip_green) - double(pH_card_rgb_array(c, 3));
                difference_blue = double(pH_strip_blue) - double(pH_card_rgb_array(c, 4));
                
                dif_red_squared = double(difference_red) * double(difference_red);
                dif_green_squared = double(difference_green) * double(difference_green);
                dif_blue_squared = double(difference_blue) * double(difference_blue);
                
                dif_total = sqrt(double(dif_red_squared + dif_green_squared + dif_blue_squared));
                    
                if dif_total < lowest_difference
                    closest_pH = pH_card_rgb_array(c, 1);
                    lowest_difference = dif_total;
                end
            end
            
            app.pHLabel.Text = num2str(closest_pH);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Plant Height'; ''})
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Plant height')
            zlabel(app.UIAxes, 'Z')
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'hydroponic_plant_height.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'hydroponic_design_top.png';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Fahrenheit', 'Celsius'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Fahrenheit';
            app.TEMPLabel = uilabel(app.UIFigure);
            app.TEMPLabel.Text = 'TEMP';
            app.LIGHTLEVLabel = uilabel(app.UIFigure);
            app.LIGHTLEVLabel.Text = 'LIGHTLEV';
            app.PlantHeightinLabel = uilabel(app.UIFigure);
            app.PlantHeightinLabel.Text = 'Plant Height (in)';
            app.PlantGrowthLabel_2 = uilabel(app.UIFigure);
            app.PlantGrowthLabel_2.Text = 'Plant Growth';
            app.pHLabel = uilabel(app.UIFigure);
            app.pHLabel.Text = 'pH';
            app.DippHstripButton = uibutton(app.UIFigure, 'push');
            app.DippHstripButton.ButtonPushedFcn = createCallbackFcn(app, @DippHstripButtonPushed, true);
            app.DippHstripButton.Text = 'Dip pH strip';
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = 'Date';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'ºF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hydroponics_Final_real

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end